classdef PCR_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AddPlotsPanel                   matlab.ui.container.Panel
        AxesMarkerFontSizeSpinner       matlab.ui.control.Spinner
        AxesMarkerFontSizeSpinnerLabel  matlab.ui.control.Label
        AxesLabelFontSizeSpinner        matlab.ui.control.Spinner
        AxesLabelFontSizeSpinnerLabel   matlab.ui.control.Label
        TitleFontSizeSpinner            matlab.ui.control.Spinner
        TitleFontSizeSpinnerLabel       matlab.ui.control.Label
        ClearPlotsButton                matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        AddWellButton                   matlab.ui.control.Button
        ColDropDown                     matlab.ui.control.DropDown
        ColDropDownLabel                matlab.ui.control.Label
        RowDropDown                     matlab.ui.control.DropDown
        RowDropDownLabel                matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % holds the app data
        Cols % all the columns that we are going to plot
        Colors % the colors associated with the columns
        LetterMap % Maps row letters to numbers
        Holding
    end
    
    methods (Access = private)
        
        function refreshPlot(app)
                                 
            
            hold(app.UIAxes,'on');
            
            % add most recent plot
            x = app.Data(:,1);
            y = app.Data(:, app.Cols(end));
            plot(app.UIAxes, x, y, app.Colors{end})
                
            
            %%%% CREATE PLOTTING FEATURES %%%%
            
            % title and axes labels
            app.UIAxes.Title.String = "Heating Plot";
            app.UIAxes.XLabel.String = "Temperature (°C)";
            app.UIAxes.YLabel.String = "ARB";
            
           
            % font size adjustment for title, axes, and markers
            app.UIAxes.FontSize = app.AxesMarkerFontSizeSpinner.Value;                       
            app.UIAxes.LabelFontSizeMultiplier = app.AxesLabelFontSizeSpinner.Value/app.AxesMarkerFontSizeSpinner.Value;
            app.UIAxes.TitleFontSizeMultiplier = app.TitleFontSizeSpinner.Value/app.AxesMarkerFontSizeSpinner.Value;


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)

            % get file data (FROM TEXT FILE)
            filename = uigetfile({'*.*','Select the PCR data file.'});
            app.Data = readmatrix(filename);    
            
            
            app.Cols = [];
            app.Colors = {};
            app.Holding = false;  % is holding needed???
            
            % initialize HashMap mapping letters to row number
            letterKeys = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H'};
            letterVals = [0, 1, 2, 3, 4, 5, 6, 7];
            app.LetterMap = containers.Map(letterKeys, letterVals);
            
            
        end

        % Button pushed function: AddWellButton
        function AddWellButtonPushed(app, event)
            % converts well provided into an integer index 
            row = app.RowDropDown.Value;
            col = str2num(app.ColDropDown.Value);
            index = (2 * col) + (24 * app.LetterMap(row));
            
            app.Holding = true; % DO YOU NEED THIS?
            
            % updates Cols and Colors with most recent plot values
            app.Cols(end + 1) = index;
            app.Colors{end + 1} = app.ColorDropDown.Value;
            
            refreshPlot(app);
            
        end

        % Button pushed function: ClearPlotsButton
        function ClearPlotsButtonPushed(app, event)
            
            % reset everything
            hold(app.UIAxes,'off');
            cla(app.UIAxes, 'reset');  % THIS ERASES THE AXES AND STUFF TOO :(
            app.Cols = [];
            app.Colors = {};
            
            app.UIAxes.Title.String = "Heating Plot";
            app.UIAxes.XLabel.String = "Temperature (°C)";
            app.UIAxes.YLabel.String = "ARB";
            
            
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            
            % delete most recent plot stored values
            app.Cols(end) = [];
            app.Colors = app.Colors(1:end - 1);
            
            hold(app.UIAxes,'off');
            cla(app.UIAxes, 'reset');
            
            app.UIAxes.Title.String = "Heating Plot";
            app.UIAxes.XLabel.String = "Temperature (°C)";
            app.UIAxes.YLabel.String = "ARB";
            
            % replot other plots
            if size(app.Cols) ~= 0
                hold(app.UIAxes, 'on');
                x = app.Data(:,1);
                for i = 1:length(app.Cols)
                    y = app.Data(:, app.Cols(i));
                    plot(app.UIAxes, x, y, app.Colors{i})
                end
            end
                        
        end

        % Value changed function: TitleFontSizeSpinner
        function TitleFontSizeSpinnerValueChanged(app, event)
            
            refreshPlot(app);
            
        end

        % Value changed function: AxesLabelFontSizeSpinner
        function AxesLabelFontSizeSpinnerValueChanged(app, event)

            refreshPlot(app);
            
        end

        % Value changed function: AxesMarkerFontSizeSpinner
        function AxesMarkerFontSizeSpinnerValueChanged(app, event)

            refreshPlot(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Heating Plot')
            xlabel(app.UIAxes, 'Temperature (C)')
            ylabel(app.UIAxes, 'ARB')
            zlabel(app.UIAxes, 'Z')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.AddPlotsPanel = uipanel(app.UIFigure);
            app.AddPlotsPanel.Title = 'Add Plots';
            app.RowDropDownLabel = uilabel(app.AddPlotsPanel);
            app.RowDropDownLabel.Text = 'Row';
            app.RowDropDown = uidropdown(app.AddPlotsPanel);
            app.RowDropDown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H'};
            app.RowDropDown.Value = 'A';
            app.ColDropDownLabel = uilabel(app.AddPlotsPanel);
            app.ColDropDownLabel.Text = 'Col';
            app.ColDropDown = uidropdown(app.AddPlotsPanel);
            app.ColDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.ColDropDown.Value = '1';
            app.AddWellButton = uibutton(app.AddPlotsPanel, 'push');
            app.AddWellButton.ButtonPushedFcn = createCallbackFcn(app, @AddWellButtonPushed, true);
            app.AddWellButton.Text = 'Add Well';
            app.ColorDropDownLabel = uilabel(app.AddPlotsPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.AddPlotsPanel);
            app.ColorDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.ColorDropDown.Value = 'red';
            app.UndoButton = uibutton(app.AddPlotsPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.ClearPlotsButton = uibutton(app.AddPlotsPanel, 'push');
            app.ClearPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotsButtonPushed, true);
            app.ClearPlotsButton.Text = 'Clear Plots';
            app.TitleFontSizeSpinnerLabel = uilabel(app.AddPlotsPanel);
            app.TitleFontSizeSpinnerLabel.Text = 'Title Font Size';
            app.TitleFontSizeSpinner = uispinner(app.AddPlotsPanel);
            app.TitleFontSizeSpinner.Limits = [1 100];
            app.TitleFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @TitleFontSizeSpinnerValueChanged, true);
            app.TitleFontSizeSpinner.Value = 15;
            app.AxesLabelFontSizeSpinnerLabel = uilabel(app.AddPlotsPanel);
            app.AxesLabelFontSizeSpinnerLabel.Text = 'Axes Label Font Size';
            app.AxesLabelFontSizeSpinner = uispinner(app.AddPlotsPanel);
            app.AxesLabelFontSizeSpinner.Limits = [1 100];
            app.AxesLabelFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AxesLabelFontSizeSpinnerValueChanged, true);
            app.AxesLabelFontSizeSpinner.Value = 12;
            app.AxesMarkerFontSizeSpinnerLabel = uilabel(app.AddPlotsPanel);
            app.AxesMarkerFontSizeSpinnerLabel.Text = 'Axes Marker Font Size';
            app.AxesMarkerFontSizeSpinner = uispinner(app.AddPlotsPanel);
            app.AxesMarkerFontSizeSpinner.Limits = [1 100];
            app.AxesMarkerFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AxesMarkerFontSizeSpinnerValueChanged, true);
            app.AxesMarkerFontSizeSpinner.Value = 12;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCR_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end